classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        FilteringandtestingperformanceTab  matlab.ui.container.Tab
        LoadfileButton              matlab.ui.control.Button
        NoisereductionSpinnerLabel  matlab.ui.control.Label
        NoisereductionSpinner       matlab.ui.control.Spinner
        PacketlengthSpinnerLabel    matlab.ui.control.Label
        PacketlengthSpinner         matlab.ui.control.Spinner
        SamplingFreqSpinnerLabel    matlab.ui.control.Label
        SamplingFreqSpinner         matlab.ui.control.Spinner
        RSBSpinnerLabel             matlab.ui.control.Label
        RSBSpinner                  matlab.ui.control.Spinner
        Spectrogram                 matlab.ui.control.UIAxes
        PlayPauseButton             matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        LoadingSignalLabel          matlab.ui.control.Label
        FilteringSettingsLabel      matlab.ui.control.Label
        ButtonGroup                 matlab.ui.container.ButtonGroup
        OriginalButton              matlab.ui.control.ToggleButton
        NoisedButton                matlab.ui.control.ToggleButton
        FilteredButton              matlab.ui.control.ToggleButton
        WindowDropDownLabel         matlab.ui.control.Label
        WindowDropDown              matlab.ui.control.DropDown
        ColorDropDownLabel          matlab.ui.control.Label
        ColorDropDown               matlab.ui.control.DropDown
        SyntheticNoiseLabel         matlab.ui.control.Label
        SignaltoplayLabel_2         matlab.ui.control.Label
        AddnoiseSwitchLabel         matlab.ui.control.Label
        AddnoiseSwitch              matlab.ui.control.Switch
        SerrariMohamedLabel         matlab.ui.control.Label
        CorrelationTab              matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        VarianceEditFieldLabel      matlab.ui.control.Label
        VarianceEditField           matlab.ui.control.NumericEditField
        MeanEditFieldLabel          matlab.ui.control.Label
        MeanEditField               matlab.ui.control.NumericEditField
        BiasedCorrelationButton     matlab.ui.control.StateButton
        UnbiasedCorrelationButton   matlab.ui.control.StateButton
        OriginalSignalButton        matlab.ui.control.StateButton
        SelectasignalLabel          matlab.ui.control.Label
        ColorDropLabel              matlab.ui.control.Label
        SelectNoiseColor            matlab.ui.control.DropDown
        SamplesEditFieldLabel       matlab.ui.control.Label
        SamplesEditField            matlab.ui.control.NumericEditField
        PlotButton                  matlab.ui.control.Button
        SerrariMohamedLabel_2       matlab.ui.control.Label
        PeriodogramTab              matlab.ui.container.Tab
        PeriodogramAxes             matlab.ui.control.UIAxes
        PlotPeriodogram             matlab.ui.control.Button
        SerrariMohamedLabel_3       matlab.ui.control.Label
        ColorDropLabel_2            matlab.ui.control.Label
        SelectNoiseColor_2          matlab.ui.control.DropDown
        VarianceEditField_2Label    matlab.ui.control.Label
        VarianceEditField_2         matlab.ui.control.NumericEditField
        MeanEditField_2Label        matlab.ui.control.Label
        MeanEditField_2             matlab.ui.control.NumericEditField
        SamplesEditField_2Label     matlab.ui.control.Label
        SamplesEditField_2          matlab.ui.control.NumericEditField
        WelshCheckBox               matlab.ui.control.CheckBox
        OverLapSliderLabel          matlab.ui.control.Label
        OverLapSlider               matlab.ui.control.Slider
        SegmentSizeSpinnerLabel     matlab.ui.control.Label
        SegmentSizeSpinner          matlab.ui.control.Spinner
        BartlettCheckBox            matlab.ui.control.CheckBox
        SegmentSizeSpinner_2Label   matlab.ui.control.Label
        SegmentSizeSpinner_2        matlab.ui.control.Spinner
        DaniellCheckBox             matlab.ui.control.CheckBox
        WindowSizeSpinnerLabel      matlab.ui.control.Label
        WindowSizeSpinner           matlab.ui.control.Spinner
    end


    properties (Access = private)
        signal          % line matrix holding loaded signal
        signal_noise    % Signal with noise
        signal_filt     % Signal filtered
        sigma_noise2    % Variance of noise
        window_type     % The window chosen by user
        playing = 0;    % If audio is played 1 if paused 2
        player;
        changed = true;    % equals to 1 if settings changed
    end

    methods (Access = private)
    
        function window = window_methods(app)
            pkt_len = app.PacketlengthSpinner.Value;
            switch app.WindowDropDown.Value
                case 'Hamming'
                    window = hamming(pkt_len).';
                case 'Hann'
                    window = hann(pkt_len).';
                case 'Rectangle'
                    window = ones(1, pkt_len);
            end
        end
        
        function noise = genNoise(app)
            N = app.SamplesEditField.Value;
            color = app.SelectNoiseColor.Value;
            var = app.VarianceEditField.Value;
            mu  = app.MeanEditField.Value;
            cn = dsp.ColoredNoise('Color', color, 'SamplesPerFrame', N);
            noise = sqrt(var) * cn().' + mu;
        end
        
        function noise = genNoise2(app)
            N = app.SamplesEditField_2.Value;
            color = app.SelectNoiseColor_2.Value;
            var = app.VarianceEditField_2.Value;
            mu  = app.MeanEditField_2.Value;
            cn = dsp.ColoredNoise('Color', color, 'SamplesPerFrame', N);
            noise = sqrt(var) * cn().' + mu;
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            
            if isempty(file)
                msgbox("Please select a file to load", 'Error','error');
            else
                data = struct2cell(load(strcat(path, file)));
                app.signal = data{1}.';
                app.changed = true;
            end
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            play_flag = true;
            if (isempty(app.signal))
                LoadfileButtonPushed(app, event);
            end
            if app.changed == true 
                if app.playing ~= 0
                    stop(app.player);
                    app.playing = 0;
                end
                
                pkt_len = app.PacketlengthSpinner.Value;
                nfft    = 2.^nextpow2(pkt_len);
                fe      = app.SamplingFreqSpinner.Value;
                rsb     = app.RSBSpinner.Value;
                color   = app.ColorDropDown.Value;
                
                switch app.ButtonGroup.SelectedObject.Text
                    case 'Original'
                        [S,F,T] = spectrogram(app.signal, pkt_len, .5*pkt_len, nfft, fe, 'yaxis');
                        imagesc(app.Spectrogram, T, F, log(abs(S)));
                        app.Spectrogram.YLim = [0 (fe/2)];
                        app.Spectrogram.YLabel.String = 'Frequency (Hz)';
                        app.Spectrogram.XLim = [1 floor(length(app.signal)/fe)];
                        app.Spectrogram.XLabel.String = 'Time (s)';
                        app.Spectrogram.YDir = 'normal';
                        app.Spectrogram.Title.String = 'Spectrogramm of original signal';
                        
                        app.player = audioplayer(app.signal/max(abs(app.signal)), fe);
                    case 'Noised'
                        if (app.AddnoiseSwitch.Value == "On")
                            %% Adding noise to signal
                            [app.signal_noise, app.sigma_noise2] = add_colored_noise(rsb, app.signal, color);
                            
                            [S,F,T] = spectrogram(app.signal_noise/max(abs(app.signal_noise)), pkt_len, .5*pkt_len, nfft, fe, 'yaxis');
                            imagesc(app.Spectrogram, T, F, log(abs(S)));
                            app.Spectrogram.YLim = [0 (fe/2)];
                            app.Spectrogram.YLabel.String = 'Frequency (Hz)';
                            app.Spectrogram.XLim = [1 floor(length(app.signal_noise)/fe)];
                            app.Spectrogram.XLabel.String = 'Time (s)';
                            app.Spectrogram.YDir = 'normal';
                            app.Spectrogram.Title.String = 'Spectrogramm of noised signal';
                            
                            app.player = audioplayer(app.signal_noise/max(abs(app.signal_noise)), fe);
                        else
                            msgbox("You can't play noised signal when the option to add noise is set to 'off'", 'Error','error');
                            play_flag = false;
                        end
                        
                    case 'Filtered'
                        window = window_methods(app);
                        noise_level = app.NoisereductionSpinner.Value;
                        if (app.AddnoiseSwitch.Value == "On")
                            
                            %% Adding noise to signal
                            [app.signal_noise, app.sigma_noise2] = add_colored_noise(rsb, app.signal, color);
                            
                            %% Filtering the signal
                            app.signal_filt = filter_signal(app.signal_noise, pkt_len, window, app.sigma_noise2, noise_level);
                            app.signal_filt = app.signal_filt / max(abs(app.signal_filt));
                        else
                            %% Filtering the signal
                            app.signal_filt = filter_signal(app.signal, pkt_len, window, app.sigma_noise2, noise_level);
                            app.signal_filt = app.signal_filt / max(abs(app.signal_filt));
                        end


                        [S,F,T] = spectrogram(app.signal_filt, pkt_len, .5*pkt_len, nfft, fe, 'yaxis');
                        imagesc(app.Spectrogram, T, F, log(abs(S)));
                        app.Spectrogram.YLim = [0 (fe/2)];
                        app.Spectrogram.YLabel.String = 'Frequency (Hz)';
                        app.Spectrogram.XLim = [1 floor(length(app.signal_filt)/fe)];
                        app.Spectrogram.XLabel.String = 'Time (s)';
                        app.Spectrogram.YDir = 'normal';
                        app.Spectrogram.Title.String = 'Spectrogramm of filtered signal';
                        
                        %% Starting player
                        app.player = audioplayer(app.signal_filt, fe);
                end
                app.changed = false;
            end
            if play_flag == true
                if app.playing == 0     % Nothing is playing                  
                    play(app.player);
                    app.playing = 1;
                elseif app.playing == 1 % Something is playing so we pause
                    app.playing = 2;
                    pause(app.player);
                else
                    app.playing = 1;    % player is paused so we resume
                    resume(app.player);
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonValueChanged(app, event)
            if app.playing ~= 0
                stop(app.player);
                app.playing = 0;
            end
        end

        % Callback function: ButtonGroup, ColorDropDown, 
        % NoisereductionSpinner, PacketlengthSpinner, RSBSpinner, 
        % SamplingFreqSpinner, WindowDropDown
        function ParameterValuesChanged(app, event)
            app.changed = true;
        end

        % Value changed function: AddnoiseSwitch
        function AddnoiseSwitchValueChanged(app, event)
            app.changed = true;
            
            if app.AddnoiseSwitch.Value == "On"
                % Create NoisedButton
                app.NoisedButton = uitogglebutton(app.ButtonGroup);
                app.NoisedButton.VerticalAlignment = 'bottom';
                app.NoisedButton.Text = 'Noised';
                app.NoisedButton.FontSize = 14;
                app.NoisedButton.FontWeight = 'bold';
                app.NoisedButton.Position = [7 29 128 26];
            else
                app.NoisedButton.delete;
            end

        end

        % Button pushed function: PlotButton
        function PlotCorrelation(app, event)
            state_biased = app.BiasedCorrelationButton.Value;
            state_unbias = app.UnbiasedCorrelationButton.Value;

%             app.UIAxes.reset
            
            noise = genNoise(app);
            
            app.UIAxes.cla;
            
            app.UIAxes.Title.String = 'Correlation function';
            app.UIAxes.NextPlot     = 'add';

            if state_biased
                [c_b  , lags_b]   = xcorr(noise, 'biased');
                plot(app.UIAxes, lags_b, c_b);
            end
            
            if state_unbias
                [c_unb  , lags_unb]   = xcorr(noise, 'unbiased');
                plot(app.UIAxes, lags_unb, c_unb);
            end
                                   
        end

        % Value changed function: BiasedCorrelationButton, 
        % UnbiasedCorrelationButton
        function CorrelationButtonClicked(app, event)
            if app.BiasedCorrelationButton.Value || app.UnbiasedCorrelationButton.Value
                app.OriginalSignalButton.Enable = "Off";
            else
                app.OriginalSignalButton.Enable = "On";
            end
        end

        % Button pushed function: PlotPeriodogram
        function PlotPeriodogramButtonPushed(app, event)
            noise = genNoise2(app);
            noise_size = length(noise);
            p = nextpow2(noise_size);
            nfft = 2^p;
            spectre = (abs(fftshift(fft(noise, nfft))).^2)/noise_size;
            freq_axe = linspace(-.5, .5 - 1/nfft, nfft);
            
            
            app.PeriodogramAxes.cla;
            
            app.PeriodogramAxes.Title.String = 'Periodogramms';
            app.PeriodogramAxes.NextPlot     = 'add';
            
            boolLegends = [0,0,0];
            
            if app.DaniellCheckBox.Value
                %% Methode de Daniell
                window_size = app.WindowSizeSpinner.Value;
                
                window = ones(1, window_size)/window_size;
                extended_spectre = [spectre(1,end-window_size:end) spectre spectre(1, 1:window_size)];
                spectre_moyenne = conv(extended_spectre, window, 'same');
                spectre_trimmed = spectre_moyenne(window_size+1:end-window_size-1) ;
                
                plot(app.PeriodogramAxes, freq_axe, spectre_trimmed, 'r');
                boolLegends(1) = 1;
            end
            
            if app.BartlettCheckBox.Value
                %% Methode de Bartlett
                SegmentSize = app.SegmentSizeSpinner_2.Value;
                Overlap = 0;
                
                [periodogramme, freq_axe] = Welsh(noise, SegmentSize, Overlap);
                plot(app.PeriodogramAxes, freq_axe, periodogramme, 'g');
                boolLegends(2) = 1;
            end
            
            if app.WelshCheckBox.Value
                %% Methode de Welsh     
                SegmentSize = app.SegmentSizeSpinner.Value;
                Overlap = app.OverLapSlider.Value;
                
                [periodogramme, freq_axe] = Welsh(noise, SegmentSize, Overlap);
                plot(app.PeriodogramAxes, freq_axe, periodogramme, 'b');
                boolLegends(3) = 1;
            end
            legends = {"Daniell's method", "Bartlett's method", "Welsh's method"};
            legend(app.PeriodogramAxes, legends(boolean(boolLegends)));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilteringandtestingperformanceTab = uitab(app.TabGroup);
            app.FilteringandtestingperformanceTab.Title = 'Filtering and testing performance';
            app.LoadfileButton = uibutton(app.FilteringandtestingperformanceTab, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Icon = 'Load_file.png';
            app.LoadfileButton.Text = 'Load file';
            app.NoisereductionSpinnerLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.NoisereductionSpinnerLabel.Text = 'Noise reduction';
            app.NoisereductionSpinner = uispinner(app.FilteringandtestingperformanceTab);
            app.NoisereductionSpinner.Step = 0.25;
            app.NoisereductionSpinner.Limits = [0 Inf];
            app.NoisereductionSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.NoisereductionSpinner.Value = 3;
            app.PacketlengthSpinnerLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.PacketlengthSpinnerLabel.Text = 'Packet length';
            app.PacketlengthSpinner = uispinner(app.FilteringandtestingperformanceTab);
            app.PacketlengthSpinner.Step = 2;
            app.PacketlengthSpinner.Limits = [0 500];
            app.PacketlengthSpinner.ValueDisplayFormat = '%11g';
            app.PacketlengthSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.PacketlengthSpinner.Value = 400;
            app.SamplingFreqSpinnerLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.SamplingFreqSpinnerLabel.Text = 'Sampling Freq';
            app.SamplingFreqSpinner = uispinner(app.FilteringandtestingperformanceTab);
            app.SamplingFreqSpinner.Step = 0.25;
            app.SamplingFreqSpinner.Limits = [0 Inf];
            app.SamplingFreqSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.SamplingFreqSpinner.Value = 8000;
            app.RSBSpinnerLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.RSBSpinnerLabel.Text = 'RSB';
            app.RSBSpinner = uispinner(app.FilteringandtestingperformanceTab);
            app.RSBSpinner.Limits = [0 Inf];
            app.RSBSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.RSBSpinner.Value = 10;
            app.Spectrogram = uiaxes(app.FilteringandtestingperformanceTab);
            title(app.Spectrogram, 'Spectrogram of original signal')
            xlabel(app.Spectrogram, 'Time (s)')
            ylabel(app.Spectrogram, 'Frequencies Hz')
            app.PlayPauseButton = uibutton(app.FilteringandtestingperformanceTab, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Icon = 'PlayPause.png';
            app.PlayPauseButton.Text = 'Play / Pause';
            app.StopButton = uibutton(app.FilteringandtestingperformanceTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Icon = 'Stop.png';
            app.StopButton.Text = 'Stop';
            app.LoadingSignalLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.LoadingSignalLabel.Text = 'Loading Signal';
            app.FilteringSettingsLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.FilteringSettingsLabel.Text = 'Filtering Settings';
            app.ButtonGroup = uibuttongroup(app.FilteringandtestingperformanceTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.OriginalButton = uitogglebutton(app.ButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.NoisedButton = uitogglebutton(app.ButtonGroup);
            app.NoisedButton.Text = 'Noised';
            app.FilteredButton = uitogglebutton(app.ButtonGroup);
            app.FilteredButton.Text = 'Filtered';
            app.WindowDropDownLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.FilteringandtestingperformanceTab);
            app.WindowDropDown.Items = {'Hamming', 'Hann', 'Rectangle'};
            app.WindowDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.WindowDropDown.Value = 'Hamming';
            app.ColorDropDownLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.FilteringandtestingperformanceTab);
            app.ColorDropDown.Items = {'White', 'Pink', 'Blue', 'Brown', 'Purple'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.ColorDropDown.Value = 'White';
            app.SyntheticNoiseLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.SyntheticNoiseLabel.Text = 'Synthetic Noise';
            app.SignaltoplayLabel_2 = uilabel(app.FilteringandtestingperformanceTab);
            app.SignaltoplayLabel_2.Text = 'Signal to play';
            app.AddnoiseSwitchLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.AddnoiseSwitchLabel.Text = 'Add noise';
            app.AddnoiseSwitch = uiswitch(app.FilteringandtestingperformanceTab, 'slider');
            app.AddnoiseSwitch.ValueChangedFcn = createCallbackFcn(app, @AddnoiseSwitchValueChanged, true);
            app.AddnoiseSwitch.Value = 'On';
            app.SerrariMohamedLabel = uilabel(app.FilteringandtestingperformanceTab);
            app.SerrariMohamedLabel.Text = 'Serrari Mohamed';
            app.CorrelationTab = uitab(app.TabGroup);
            app.CorrelationTab.Title = 'Correlation';
            app.UIAxes = uiaxes(app.CorrelationTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.VarianceEditFieldLabel = uilabel(app.CorrelationTab);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.CorrelationTab, 'numeric');
            app.VarianceEditField.Limits = [0 Inf];
            app.VarianceEditField.Value = 1;
            app.MeanEditFieldLabel = uilabel(app.CorrelationTab);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.CorrelationTab, 'numeric');
            app.BiasedCorrelationButton = uibutton(app.CorrelationTab, 'state');
            app.BiasedCorrelationButton.ValueChangedFcn = createCallbackFcn(app, @CorrelationButtonClicked, true);
            app.BiasedCorrelationButton.Text = 'Biased Correlation';
            app.UnbiasedCorrelationButton = uibutton(app.CorrelationTab, 'state');
            app.UnbiasedCorrelationButton.ValueChangedFcn = createCallbackFcn(app, @CorrelationButtonClicked, true);
            app.UnbiasedCorrelationButton.Text = 'Unbiased Correlation';
            app.OriginalSignalButton = uibutton(app.CorrelationTab, 'state');
            app.OriginalSignalButton.Text = 'Original Signal';
            app.SelectasignalLabel = uilabel(app.CorrelationTab);
            app.SelectasignalLabel.Text = 'Select a signal';
            app.ColorDropLabel = uilabel(app.CorrelationTab);
            app.ColorDropLabel.Text = 'Color';
            app.SelectNoiseColor = uidropdown(app.CorrelationTab);
            app.SelectNoiseColor.Items = {'White', 'Pink', 'Blue', 'Brown', 'Purple'};
            app.SelectNoiseColor.Value = 'White';
            app.SamplesEditFieldLabel = uilabel(app.CorrelationTab);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.CorrelationTab, 'numeric');
            app.SamplesEditField.Limits = [100 Inf];
            app.SamplesEditField.Value = 1000;
            app.PlotButton = uibutton(app.CorrelationTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCorrelation, true);
            app.PlotButton.Text = 'Plot';
            app.SerrariMohamedLabel_2 = uilabel(app.CorrelationTab);
            app.SerrariMohamedLabel_2.Text = 'Serrari Mohamed';
            app.PeriodogramTab = uitab(app.TabGroup);
            app.PeriodogramTab.Title = 'Periodogram';
            app.PeriodogramAxes = uiaxes(app.PeriodogramTab);
            title(app.PeriodogramAxes, 'Title')
            xlabel(app.PeriodogramAxes, 'X')
            ylabel(app.PeriodogramAxes, 'Y')
            app.PlotPeriodogram = uibutton(app.PeriodogramTab, 'push');
            app.PlotPeriodogram.ButtonPushedFcn = createCallbackFcn(app, @PlotPeriodogramButtonPushed, true);
            app.PlotPeriodogram.Text = 'Plot';
            app.SerrariMohamedLabel_3 = uilabel(app.PeriodogramTab);
            app.SerrariMohamedLabel_3.Text = 'Serrari Mohamed';
            app.ColorDropLabel_2 = uilabel(app.PeriodogramTab);
            app.ColorDropLabel_2.Text = 'Color';
            app.SelectNoiseColor_2 = uidropdown(app.PeriodogramTab);
            app.SelectNoiseColor_2.Items = {'White', 'Pink', 'Blue', 'Brown', 'Purple'};
            app.SelectNoiseColor_2.Value = 'White';
            app.VarianceEditField_2Label = uilabel(app.PeriodogramTab);
            app.VarianceEditField_2Label.Text = 'Variance';
            app.VarianceEditField_2 = uieditfield(app.PeriodogramTab, 'numeric');
            app.VarianceEditField_2.Limits = [0 Inf];
            app.VarianceEditField_2.Value = 1;
            app.MeanEditField_2Label = uilabel(app.PeriodogramTab);
            app.MeanEditField_2Label.Text = 'Mean';
            app.MeanEditField_2 = uieditfield(app.PeriodogramTab, 'numeric');
            app.SamplesEditField_2Label = uilabel(app.PeriodogramTab);
            app.SamplesEditField_2Label.Text = 'Samples';
            app.SamplesEditField_2 = uieditfield(app.PeriodogramTab, 'numeric');
            app.SamplesEditField_2.Limits = [100 Inf];
            app.SamplesEditField_2.Value = 1000;
            app.WelshCheckBox = uicheckbox(app.PeriodogramTab);
            app.WelshCheckBox.Text = 'Welsh';
            app.OverLapSliderLabel = uilabel(app.PeriodogramTab);
            app.OverLapSliderLabel.Text = 'OverLap';
            app.OverLapSlider = uislider(app.PeriodogramTab);
            app.OverLapSlider.Limits = [0 1];
            app.OverLapSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.OverLapSlider.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.OverLapSlider.Value = 0.5;
            app.SegmentSizeSpinnerLabel = uilabel(app.PeriodogramTab);
            app.SegmentSizeSpinnerLabel.Text = 'Segment Size';
            app.SegmentSizeSpinner = uispinner(app.PeriodogramTab);
            app.SegmentSizeSpinner.Limits = [0 Inf];
            app.SegmentSizeSpinner.Value = 200;
            app.BartlettCheckBox = uicheckbox(app.PeriodogramTab);
            app.BartlettCheckBox.Text = 'Bartlett';
            app.SegmentSizeSpinner_2Label = uilabel(app.PeriodogramTab);
            app.SegmentSizeSpinner_2Label.Text = 'Segment Size';
            app.SegmentSizeSpinner_2 = uispinner(app.PeriodogramTab);
            app.SegmentSizeSpinner_2.Limits = [0 Inf];
            app.SegmentSizeSpinner_2.Value = 200;
            app.DaniellCheckBox = uicheckbox(app.PeriodogramTab);
            app.DaniellCheckBox.Text = 'Daniell';
            app.WindowSizeSpinnerLabel = uilabel(app.PeriodogramTab);
            app.WindowSizeSpinnerLabel.Text = 'Window Size';
            app.WindowSizeSpinner = uispinner(app.PeriodogramTab);
            app.WindowSizeSpinner.Limits = [0 Inf];
            app.WindowSizeSpinner.Value = 200;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end